classdef systemModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        ModeMenu                      matlab.ui.container.Menu
        SimulationMenu                matlab.ui.container.Menu
        AnalysisMenu                  matlab.ui.container.Menu
        ShortcutsMenu                 matlab.ui.container.Menu
        DroptobaseworkspaceMenu       matlab.ui.container.Menu
        UpdatesystemMenu              matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        SystemTab                     matlab.ui.container.Tab
        TxButton                      matlab.ui.control.Button
        aButton                       matlab.ui.control.Button
        CouplingButton                matlab.ui.control.Button
        TXantennaButton               matlab.ui.control.Button
        DButton                       matlab.ui.control.Button
        dButton                       matlab.ui.control.Button
        RButton_2                     matlab.ui.control.Button
        R0Button                      matlab.ui.control.Button
        TagButton                     matlab.ui.control.Button
        CButton                       matlab.ui.control.Button
        BackgroundButton              matlab.ui.control.Button
        OButton                       matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        SourceButton                  matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        RXantennaButton               matlab.ui.control.Button
        DetectorButton                matlab.ui.control.Button
        bButton                       matlab.ui.control.Button
        D_dButton                     matlab.ui.control.Button
        T_RButton                     matlab.ui.control.Button
        T_R0Button                    matlab.ui.control.Button
        RButton_3                     matlab.ui.control.Button
        R0Button_2                    matlab.ui.control.Button
        C_RButton                     matlab.ui.control.Button
        O_R0Button                    matlab.ui.control.Button
        RxButton                      matlab.ui.control.Button
        dEditFieldLabel               matlab.ui.control.Label
        dEditField                    matlab.ui.control.NumericEditField
        REditFieldLabel               matlab.ui.control.Label
        REditField                    matlab.ui.control.NumericEditField
        R0EditFieldLabel              matlab.ui.control.Label
        R0EditField                   matlab.ui.control.NumericEditField
        NoiseButton                   matlab.ui.control.Button
        NoiseButton_2                 matlab.ui.control.Button
        UpdateButton                  matlab.ui.control.Button
        systemSideView                matlab.ui.control.UIAxes
        TagCheckBox1                  matlab.ui.control.CheckBox
        TagCheckBox2                  matlab.ui.control.CheckBox
        TagCheckBox3                  matlab.ui.control.CheckBox
        TagCheckBox4                  matlab.ui.control.CheckBox
        TagCheckBox5                  matlab.ui.control.CheckBox
        TagCheckBox6                  matlab.ui.control.CheckBox
        TagCheckBox7                  matlab.ui.control.CheckBox
        TagCheckBox8                  matlab.ui.control.CheckBox
        TxHeightEditFieldLabel        matlab.ui.control.Label
        TxHeightEditField             matlab.ui.control.NumericEditField
        RxHeightEditFieldLabel        matlab.ui.control.Label
        RxHeightEditField             matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        ADCButton                     matlab.ui.control.Button
        digitizedButton               matlab.ui.control.Button
        samplerateEditFieldLabel      matlab.ui.control.Label
        sampleRateEditField           matlab.ui.control.NumericEditField
        Plots                         matlab.ui.container.Panel
        impulseResponse               matlab.ui.control.UIAxes
        amplitudeResponse             matlab.ui.control.UIAxes
        phaseResponse                 matlab.ui.control.UIAxes
        analogsimrateEditFieldLabel   matlab.ui.control.Label
        analogsimrateEditField        matlab.ui.control.NumericEditField
        scatterPointsEditFieldLabel   matlab.ui.control.Label
        scatterPointsEditField        matlab.ui.control.NumericEditField
        GroundDepthEditFieldLabel     matlab.ui.control.Label
        GroundDepthEditField          matlab.ui.control.NumericEditField
        permittivity1EditFieldLabel   matlab.ui.control.Label
        permittivity1EditField        matlab.ui.control.NumericEditField
        permittivity2EditFieldLabel   matlab.ui.control.Label
        permittivity2EditField        matlab.ui.control.NumericEditField
        permittivity3EditFieldLabel   matlab.ui.control.Label
        permittivity3EditField        matlab.ui.control.NumericEditField
        permeability1EditFieldLabel   matlab.ui.control.Label
        permeability1EditField        matlab.ui.control.NumericEditField
        permeability2EditFieldLabel   matlab.ui.control.Label
        permeability2EditField        matlab.ui.control.NumericEditField
        permeability3EditFieldLabel   matlab.ui.control.Label
        permeability3EditField        matlab.ui.control.NumericEditField
        SetupTab                      matlab.ui.container.Tab
        droptobaseworkspaceButton     matlab.ui.control.Button
        blockSetupTab                 matlab.ui.container.Tab
        gainEditFieldLabel            matlab.ui.control.Label
        gainEditField                 matlab.ui.control.NumericEditField
        systemBlockList               matlab.ui.container.TabGroup
        SourceTab                     matlab.ui.container.Tab
        TX_antennaTab                 matlab.ui.container.Tab
        AntennaPatternEditFieldLabel  matlab.ui.control.Label
        AntennaPatternEditField       matlab.ui.control.EditField
        CouplingTab                   matlab.ui.container.Tab
        TagTab                        matlab.ui.container.Tab
        BackgroundTab                 matlab.ui.container.Tab
        RX_antennaTab                 matlab.ui.container.Tab
        gainEditField_2Label          matlab.ui.control.Label
        gainEditField_2               matlab.ui.control.NumericEditField
        dTab                          matlab.ui.container.Tab
        RTab                          matlab.ui.container.Tab
        R0Tab                         matlab.ui.container.Tab
        SaveButton                    matlab.ui.control.Button
        savedVariablesinappDataTextAreaLabel  matlab.ui.control.Label
        savedVariablesinappDataTextArea  matlab.ui.control.TextArea
        DetectionTab                  matlab.ui.container.Tab
        FriisTab                      matlab.ui.container.Tab
        RtxtagmEditFieldLabel         matlab.ui.control.Label
        RtxtagmEditField              matlab.ui.control.NumericEditField
        RtagrxmEditFieldLabel         matlab.ui.control.Label
        RtagrxmEditField              matlab.ui.control.NumericEditField
        tagefficiencyEditFieldLabel   matlab.ui.control.Label
        tagefficiencyEditField        matlab.ui.control.NumericEditField
        PtagEditFieldLabel            matlab.ui.control.Label
        PtagEditField                 matlab.ui.control.NumericEditField
        PreceivedEditFieldLabel       matlab.ui.control.Label
        PreceivedEditField            matlab.ui.control.NumericEditField
        PtransmittedEditFieldLabel    matlab.ui.control.Label
        PtransmittedEditField         matlab.ui.control.NumericEditField
        frequencyEditField            matlab.ui.control.NumericEditField
        frequencyMhzEditFieldLabel    matlab.ui.control.Label
        D_txEditField                 matlab.ui.control.NumericEditField
        D_txLabel                     matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        Image_2                       matlab.ui.control.Image
        Image_3                       matlab.ui.control.Image
        Image_4                       matlab.ui.control.Image
        D_tagEditField                matlab.ui.control.NumericEditField
        D_tagLabel                    matlab.ui.control.Label
        D_rxEditField                 matlab.ui.control.NumericEditField
        D_rxLabel                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        shift=0
        enabledTags

        TxPolarAxis % Description
    end
    
    properties (Access = public)
        systemStruct
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.systemBlockList.Position=([23,207,1190,562]); 
            
            app.systemStruct=systeminit(app);
            appData=matfile('appData',"Writable",true);
            appData.systemStruct=app.systemStruct;
            app.savedVariablesinappDataTextArea.Value=who(appData);
            
%             app.TxPolarAxis=polaraxes(app.UIFigure);
%             app.TxPolarAxis.Position=[398,280,439,385];
%             polarplot(app.TxPolarAxis, (-1:0.1:1).^2);
        end

        % Button pushed function: CButton, C_RButton, DButton, 
        % D_dButton, OButton, O_R0Button, RxButton, T_R0Button, 
        % T_RButton, TxButton, aButton, bButton
        function plotSignalButton(app, event)
            app.Plots.Title=event.Source.Text;
            assignin("base","event",app.Plots)
            plotSignal(app,app.Plots.Title,app.systemStruct.simSampleRate)
        end

        % Button pushed function: BackgroundButton, CouplingButton, 
        % R0Button, R0Button_2, RButton_2, RButton_3, 
        % RXantennaButton, SourceButton, TXantennaButton, 
        % TagButton, dButton
        function systemBlockButtonPushed(app, event)
            block=strrep( event.Source.Text , ' ' , '_' );
            if app.shift==0
                plotBlockResponse(app,block,app.systemStruct.simSampleRate);
            else
                app.systemBlockList.SelectedTab=app.(strcat(block,'Tab'));
                app.TabGroup.SelectedTab=app.blockSetupTab;
                app.shift=0;
            end
            
        end

        % Window key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            % assignin("base","event",event)
            switch key
                case 'shift'
                    app.shift=1;
                case 'escape'
                    delete(app)
                case '1'
                    app.TabGroup.SelectedTab=app.SystemTab;
                case '2'
                    app.TabGroup.SelectedTab=app.SetupTab;
                case '3'
                    app.TabGroup.SelectedTab=app.blockSetupTab;
                case '4'
                    app.TabGroup.SelectedTab=app.DetectionTab;
                case '5'
                    app.TabGroup.SelectedTab=app.FriisTab;
            end
        end

        % Callback function: DroptobaseworkspaceMenu, 
        % droptobaseworkspaceButton
        function droptobaseworkspaceButtonPushed(app, event)
            assignin("base","troubleshoot",app)
        end

        % Callback function
        function InputpulseDropDownValueChanged(app, event)
            value = app.InputpulseDropDown.Value;
            switch value
                case 'Impulse'
                    app.TabGroup2.SelectedTab=app.impulsesetupTab;
                case 'short pulse'
                    app.TabGroup2.SelectedTab=app.pulsesetupTab;
            end
        end

        % Callback function: SaveButton, SaveMenu
        function SaveButtonPushed(app, event)
            app.systemStruct.(app.systemBlockList.SelectedTab.Title).response=app.gainEditField.Value;
            %assignin('base','response',(app.systemBlockList.SelectedTab.Title))%=app.gainEditField.Value;
            app.TabGroup.SelectedTab=app.SystemTab;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.systemStruct=updateSystem(app,app.systemStruct);
        end

        % Window key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            switch key
                case 'shift'
                    app.shift=0;
            end
        end

        % Value changed function: TagCheckBox1, TagCheckBox2, 
        % TagCheckBox3, TagCheckBox4, TagCheckBox5, TagCheckBox6, 
        % TagCheckBox7, TagCheckBox8
        function SelectTaglocationButtonPushed(app, event)
            app.enabledTags(:, str2double(event.Source.Text) ) = event.Source.Value;
            if event.Source.Value    
                    app.systemStruct.geometry.Tags(:, str2double(event.Source.Text) )=SelectTaglocation(app.systemStruct,app.UIFigure.Position);
                
            else
                app.systemStruct.geometry.Tags(:, str2double(event.Source.Text) )=[0;-2*app.systemStruct.geometry.GroundDepth];   
            end
            plotSideView(app.systemSideView,app.systemStruct.geometry)
        end

        % Button pushed function: DetectorButton
        function DetectorButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.DetectionTab;
        end

        % Callback function
        function EnabledtagsButtonGroupSelectionChanged(app, event)
            selectedButton = app.EnabledtagsButtonGroup.SelectedObject;
            assignin('base','buttons',event.Source)
        end

        % Button pushed function: digitizedButton
        function digitizedButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.DetectionTab;
        end

        % Value changed function: scatterPointsEditField
        function scatterPointsEditFieldValueChanged(app, event)
            N = app.scatterPointsEditField.Value;
            app.systemStruct.geometry.scatterers.x=randInRange(N,-app.systemStruct.geometry.tempWidth,app.systemStruct.geometry.tempWidth);
            app.systemStruct.geometry.scatterers.y=randInRange(N,-app.systemStruct.geometry.GroundDepth,0);
            plotSideView(app.systemSideView,app.systemStruct.geometry)
        end

        % Value changed function: PtransmittedEditField
        function PtransmittedEditFieldValueChanged(app, event)
            PTransmitted = app.PtransmittedEditField.Value;
            c0=299792458;
            Rtx = app.RtxtagmEditField.Value;
            Rrx = app.RtagrxmEditField.Value;
            tagEfficiency = app.tagefficiencyEditField.Value;
            frequency = app.frequencyEditField.Value*10e6;
            D_tx = app.D_txEditField.Value;
            D_rx = app.D_rxEditField.Value;
            D_tag = app.D_tagEditField.Value;
            
            app.PtagEditField.Value = PTransmitted*(c0/(frequency*4*pi*Rtx))^2*D_tx*D_tag;
            app.PreceivedEditField.Value = app.PtagEditField.Value*(c0/(frequency*4*pi*Rrx))^2*D_rx*D_tag;
        end

        % Menu selected function: SimulationMenu
        function SimulationMenuSelected(app, event)
            if strcmp(app.SimulationMenu.Checked,'off')
                app.SimulationMenu.Checked='on';
                app.AnalysisMenu.Checked='off';
            end
        end

        % Menu selected function: AnalysisMenu
        function AnalysisMenuSelected(app, event)
            if strcmp(app.AnalysisMenu.Checked,'off')
                app.SimulationMenu.Checked='off';
                app.AnalysisMenu.Checked='on';
                assignin("base",'tab',app.TabGroup)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ModeMenu = uimenu(app.Menu);
            app.ModeMenu.Text = 'Mode';
            app.SimulationMenu = uimenu(app.ModeMenu);
            app.SimulationMenu.MenuSelectedFcn = createCallbackFcn(app, @SimulationMenuSelected, true);
            app.SimulationMenu.Checked = 'on';
            app.SimulationMenu.Text = 'Simulation';
            app.AnalysisMenu = uimenu(app.ModeMenu);
            app.AnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @AnalysisMenuSelected, true);
            app.AnalysisMenu.Text = 'Analysis';
            app.ShortcutsMenu = uimenu(app.UIFigure);
            app.ShortcutsMenu.Text = 'Shortcuts';
            app.DroptobaseworkspaceMenu = uimenu(app.ShortcutsMenu);
            app.DroptobaseworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @droptobaseworkspaceButtonPushed, true);
            app.DroptobaseworkspaceMenu.Accelerator = 'd';
            app.DroptobaseworkspaceMenu.Text = 'Drop to base workspace';
            app.UpdatesystemMenu = uimenu(app.ShortcutsMenu);
            app.UpdatesystemMenu.Accelerator = 'u';
            app.UpdatesystemMenu.Text = 'Update system';
            app.SaveMenu = uimenu(app.ShortcutsMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SystemTab = uitab(app.TabGroup);
            app.SystemTab.Title = 'System';
            app.TxButton = uibutton(app.SystemTab, 'push');
            app.TxButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.TxButton.Text = 'Tx';
            app.aButton = uibutton(app.SystemTab, 'push');
            app.aButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.aButton.Text = 'a';
            app.CouplingButton = uibutton(app.SystemTab, 'push');
            app.CouplingButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.CouplingButton.Text = 'Coupling';
            app.TXantennaButton = uibutton(app.SystemTab, 'push');
            app.TXantennaButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.TXantennaButton.Text = 'TX antenna';
            app.DButton = uibutton(app.SystemTab, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.DButton.Text = 'D';
            app.dButton = uibutton(app.SystemTab, 'push');
            app.dButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.dButton.Text = 'd';
            app.RButton_2 = uibutton(app.SystemTab, 'push');
            app.RButton_2.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.RButton_2.Text = 'R';
            app.R0Button = uibutton(app.SystemTab, 'push');
            app.R0Button.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.R0Button.Text = 'R0';
            app.TagButton = uibutton(app.SystemTab, 'push');
            app.TagButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.TagButton.Text = 'Tag';
            app.CButton = uibutton(app.SystemTab, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.CButton.Text = 'C';
            app.BackgroundButton = uibutton(app.SystemTab, 'push');
            app.BackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.BackgroundButton.Text = 'Background';
            app.OButton = uibutton(app.SystemTab, 'push');
            app.OButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.OButton.Text = 'O';
            app.Label = uilabel(app.SystemTab);
            app.Label.Text = {'------------------------------------------------------------------'; '            |                                   |                              |'};
            app.SourceButton = uibutton(app.SystemTab, 'push');
            app.SourceButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.SourceButton.Text = 'Source';
            app.Label_2 = uilabel(app.SystemTab);
            app.Label_2.Text = {'            |                                   |                              |'; '------------------------------------------------------------------'};
            app.RXantennaButton = uibutton(app.SystemTab, 'push');
            app.RXantennaButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.RXantennaButton.Text = 'RX antenna';
            app.DetectorButton = uibutton(app.SystemTab, 'push');
            app.DetectorButton.ButtonPushedFcn = createCallbackFcn(app, @DetectorButtonPushed, true);
            app.DetectorButton.Text = 'Detector';
            app.bButton = uibutton(app.SystemTab, 'push');
            app.bButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.bButton.Text = 'b';
            app.D_dButton = uibutton(app.SystemTab, 'push');
            app.D_dButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.D_dButton.Text = 'D_d';
            app.T_RButton = uibutton(app.SystemTab, 'push');
            app.T_RButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.T_RButton.Text = 'T_R';
            app.T_R0Button = uibutton(app.SystemTab, 'push');
            app.T_R0Button.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.T_R0Button.Text = 'T_R0';
            app.RButton_3 = uibutton(app.SystemTab, 'push');
            app.RButton_3.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.RButton_3.Text = 'R';
            app.R0Button_2 = uibutton(app.SystemTab, 'push');
            app.R0Button_2.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.R0Button_2.Text = 'R0';
            app.C_RButton = uibutton(app.SystemTab, 'push');
            app.C_RButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.C_RButton.Text = 'C_R';
            app.O_R0Button = uibutton(app.SystemTab, 'push');
            app.O_R0Button.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.O_R0Button.Text = 'O_R0';
            app.RxButton = uibutton(app.SystemTab, 'push');
            app.RxButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.RxButton.Text = 'Rx';
            app.dEditFieldLabel = uilabel(app.SystemTab);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.SystemTab, 'numeric');
            app.REditFieldLabel = uilabel(app.SystemTab);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.SystemTab, 'numeric');
            app.R0EditFieldLabel = uilabel(app.SystemTab);
            app.R0EditFieldLabel.Text = 'R0';
            app.R0EditField = uieditfield(app.SystemTab, 'numeric');
            app.NoiseButton = uibutton(app.SystemTab, 'push');
            app.NoiseButton.Text = 'Noise';
            app.NoiseButton_2 = uibutton(app.SystemTab, 'push');
            app.NoiseButton_2.Text = 'Noise';
            app.UpdateButton = uibutton(app.SystemTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.systemSideView = uiaxes(app.SystemTab);
            title(app.systemSideView, 'Title')
            xlabel(app.systemSideView, 'X')
            ylabel(app.systemSideView, 'Y')
            app.TagCheckBox1 = uicheckbox(app.SystemTab);
            app.TagCheckBox1.ValueChangedFcn = createCallbackFcn(app, @SelectTaglocationButtonPushed, true);
            app.TagCheckBox1.Text = '1';
            app.TagCheckBox2 = uicheckbox(app.SystemTab);
            app.TagCheckBox2.ValueChangedFcn = createCallbackFcn(app, @SelectTaglocationButtonPushed, true);
            app.TagCheckBox2.Text = '2';
            app.TagCheckBox3 = uicheckbox(app.SystemTab);
            app.TagCheckBox3.ValueChangedFcn = createCallbackFcn(app, @SelectTaglocationButtonPushed, true);
            app.TagCheckBox3.Text = '3';
            app.TagCheckBox4 = uicheckbox(app.SystemTab);
            app.TagCheckBox4.ValueChangedFcn = createCallbackFcn(app, @SelectTaglocationButtonPushed, true);
            app.TagCheckBox4.Text = '4';
            app.TagCheckBox5 = uicheckbox(app.SystemTab);
            app.TagCheckBox5.ValueChangedFcn = createCallbackFcn(app, @SelectTaglocationButtonPushed, true);
            app.TagCheckBox5.Text = '5';
            app.TagCheckBox6 = uicheckbox(app.SystemTab);
            app.TagCheckBox6.ValueChangedFcn = createCallbackFcn(app, @SelectTaglocationButtonPushed, true);
            app.TagCheckBox6.Text = '6';
            app.TagCheckBox7 = uicheckbox(app.SystemTab);
            app.TagCheckBox7.ValueChangedFcn = createCallbackFcn(app, @SelectTaglocationButtonPushed, true);
            app.TagCheckBox7.Text = '7';
            app.TagCheckBox8 = uicheckbox(app.SystemTab);
            app.TagCheckBox8.ValueChangedFcn = createCallbackFcn(app, @SelectTaglocationButtonPushed, true);
            app.TagCheckBox8.Text = '8';
            app.TxHeightEditFieldLabel = uilabel(app.SystemTab);
            app.TxHeightEditFieldLabel.Text = 'Tx Height';
            app.TxHeightEditField = uieditfield(app.SystemTab, 'numeric');
            app.RxHeightEditFieldLabel = uilabel(app.SystemTab);
            app.RxHeightEditFieldLabel.Text = 'Rx Height';
            app.RxHeightEditField = uieditfield(app.SystemTab, 'numeric');
            app.Label_3 = uilabel(app.SystemTab);
            app.ADCButton = uibutton(app.SystemTab, 'push');
            app.ADCButton.Text = 'ADC';
            app.digitizedButton = uibutton(app.SystemTab, 'push');
            app.digitizedButton.ButtonPushedFcn = createCallbackFcn(app, @digitizedButtonPushed, true);
            app.digitizedButton.Text = 'digitized';
            app.samplerateEditFieldLabel = uilabel(app.SystemTab);
            app.samplerateEditFieldLabel.Text = 'sample  rate';
            app.sampleRateEditField = uieditfield(app.SystemTab, 'numeric');
            app.Plots = uipanel(app.SystemTab);
            app.Plots.Title = '[select a signal or system block to plot]';
            app.impulseResponse = uiaxes(app.Plots);
            title(app.impulseResponse, 'Impulse response')
            xlabel(app.impulseResponse, 'time (s)')
            ylabel(app.impulseResponse, 'Y')
            app.amplitudeResponse = uiaxes(app.Plots);
            title(app.amplitudeResponse, 'Frequency response (amplitude)')
            xlabel(app.amplitudeResponse, 'Frequency')
            ylabel(app.amplitudeResponse, '|Y|')
            app.phaseResponse = uiaxes(app.Plots);
            title(app.phaseResponse, 'Frequency response (angle)')
            xlabel(app.phaseResponse, 'Frequency')
            ylabel(app.phaseResponse, '\angleY')
            app.analogsimrateEditFieldLabel = uilabel(app.SystemTab);
            app.analogsimrateEditFieldLabel.Text = 'analog sim rate';
            app.analogsimrateEditField = uieditfield(app.SystemTab, 'numeric');
            app.scatterPointsEditFieldLabel = uilabel(app.SystemTab);
            app.scatterPointsEditFieldLabel.Text = 'scatterPoints';
            app.scatterPointsEditField = uieditfield(app.SystemTab, 'numeric');
            app.scatterPointsEditField.ValueChangedFcn = createCallbackFcn(app, @scatterPointsEditFieldValueChanged, true);
            app.GroundDepthEditFieldLabel = uilabel(app.SystemTab);
            app.GroundDepthEditFieldLabel.Text = 'GroundDepth';
            app.GroundDepthEditField = uieditfield(app.SystemTab, 'numeric');
            app.permittivity1EditFieldLabel = uilabel(app.SystemTab);
            app.permittivity1EditFieldLabel.Text = 'permittivity 1';
            app.permittivity1EditField = uieditfield(app.SystemTab, 'numeric');
            app.permittivity1EditField.Tooltip = {'Relative permetivity of the positive Y-plane'};
            app.permittivity1EditField.Value = 1;
            app.permittivity2EditFieldLabel = uilabel(app.SystemTab);
            app.permittivity2EditFieldLabel.Text = 'permittivity 2';
            app.permittivity2EditField = uieditfield(app.SystemTab, 'numeric');
            app.permittivity2EditField.Tooltip = {'Relative permetivity of the negative Y-plane'};
            app.permittivity2EditField.Value = 3;
            app.permittivity3EditFieldLabel = uilabel(app.SystemTab);
            app.permittivity3EditFieldLabel.Text = 'permittivity 3';
            app.permittivity3EditField = uieditfield(app.SystemTab, 'numeric');
            app.permittivity3EditField.Tooltip = {'Relative permetivity of the scatter points in the negative Y-plane'};
            app.permittivity3EditField.Value = 1;
            app.permeability1EditFieldLabel = uilabel(app.SystemTab);
            app.permeability1EditFieldLabel.Text = 'permeability 1';
            app.permeability1EditField = uieditfield(app.SystemTab, 'numeric');
            app.permeability1EditField.Tooltip = {'Relative permeability of the positive Y-plane'};
            app.permeability1EditField.Value = 1;
            app.permeability2EditFieldLabel = uilabel(app.SystemTab);
            app.permeability2EditFieldLabel.Text = 'permeability 2';
            app.permeability2EditField = uieditfield(app.SystemTab, 'numeric');
            app.permeability2EditField.Tooltip = {'Relative permeability of the negative Y-plane'};
            app.permeability2EditField.Value = 3;
            app.permeability3EditFieldLabel = uilabel(app.SystemTab);
            app.permeability3EditFieldLabel.Text = 'permeability 3';
            app.permeability3EditField = uieditfield(app.SystemTab, 'numeric');
            app.permeability3EditField.Tooltip = {'Relative permeability of the scatter points in the negative Y-plane'};
            app.permeability3EditField.Value = 1;
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.droptobaseworkspaceButton = uibutton(app.SetupTab, 'push');
            app.droptobaseworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @droptobaseworkspaceButtonPushed, true);
            app.droptobaseworkspaceButton.Text = 'drop to base workspace';
            app.blockSetupTab = uitab(app.TabGroup);
            app.blockSetupTab.Title = 'block setup';
            app.gainEditFieldLabel = uilabel(app.blockSetupTab);
            app.gainEditFieldLabel.Text = 'gain';
            app.gainEditField = uieditfield(app.blockSetupTab, 'numeric');
            app.systemBlockList = uitabgroup(app.blockSetupTab);
            app.SourceTab = uitab(app.systemBlockList);
            app.SourceTab.Title = 'Source';
            app.TX_antennaTab = uitab(app.systemBlockList);
            app.TX_antennaTab.Title = 'TX_antenna';
            app.AntennaPatternEditFieldLabel = uilabel(app.TX_antennaTab);
            app.AntennaPatternEditFieldLabel.Text = 'Antenna Pattern';
            app.AntennaPatternEditField = uieditfield(app.TX_antennaTab, 'text');
            app.CouplingTab = uitab(app.systemBlockList);
            app.CouplingTab.Title = 'Coupling';
            app.TagTab = uitab(app.systemBlockList);
            app.TagTab.Title = 'Tag';
            app.BackgroundTab = uitab(app.systemBlockList);
            app.BackgroundTab.Title = 'Background';
            app.RX_antennaTab = uitab(app.systemBlockList);
            app.RX_antennaTab.Title = 'RX_antenna';
            app.gainEditField_2Label = uilabel(app.RX_antennaTab);
            app.gainEditField_2Label.Text = 'gain';
            app.gainEditField_2 = uieditfield(app.RX_antennaTab, 'numeric');
            app.dTab = uitab(app.systemBlockList);
            app.dTab.Title = 'd';
            app.RTab = uitab(app.systemBlockList);
            app.RTab.Title = 'R';
            app.R0Tab = uitab(app.systemBlockList);
            app.R0Tab.Title = 'R0';
            app.SaveButton = uibutton(app.blockSetupTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.savedVariablesinappDataTextAreaLabel = uilabel(app.blockSetupTab);
            app.savedVariablesinappDataTextAreaLabel.Text = {'saved Variables'; 'in appData'};
            app.savedVariablesinappDataTextArea = uitextarea(app.blockSetupTab);
            app.DetectionTab = uitab(app.TabGroup);
            app.DetectionTab.Title = 'Detection';
            app.FriisTab = uitab(app.TabGroup);
            app.FriisTab.Title = 'Friis';
            app.RtxtagmEditFieldLabel = uilabel(app.FriisTab);
            app.RtxtagmEditFieldLabel.Text = 'R tx-tag (m)';
            app.RtxtagmEditField = uieditfield(app.FriisTab, 'numeric');
            app.RtxtagmEditField.Tooltip = {'distance between the transmitting antenna and tag in meters'};
            app.RtxtagmEditField.Value = 0.5;
            app.RtagrxmEditFieldLabel = uilabel(app.FriisTab);
            app.RtagrxmEditFieldLabel.Tooltip = {'case ''4'''; '                    app.TabGroup.SelectedTab=app.DetectionTab'; ''};
            app.RtagrxmEditFieldLabel.Text = 'R tag-rx (m)';
            app.RtagrxmEditField = uieditfield(app.FriisTab, 'numeric');
            app.RtagrxmEditField.Tooltip = {'distance between the tag and receiving antenna in meters'};
            app.RtagrxmEditField.Value = 0.5;
            app.tagefficiencyEditFieldLabel = uilabel(app.FriisTab);
            app.tagefficiencyEditFieldLabel.Tooltip = {'case ''4'''; '                    app.TabGroup.SelectedTab=app.DetectionTab'; ''};
            app.tagefficiencyEditFieldLabel.Text = 'tag efficiency';
            app.tagefficiencyEditField = uieditfield(app.FriisTab, 'numeric');
            app.tagefficiencyEditField.Tooltip = {'re-radiation efficiency factor  in range 0 to 1'};
            app.tagefficiencyEditField.Value = 1;
            app.PtagEditFieldLabel = uilabel(app.FriisTab);
            app.PtagEditFieldLabel.Text = 'P tag';
            app.PtagEditField = uieditfield(app.FriisTab, 'numeric');
            app.PtagEditField.Editable = 'off';
            app.PtagEditField.Tooltip = {'power received by tag antenna structure'};
            app.PreceivedEditFieldLabel = uilabel(app.FriisTab);
            app.PreceivedEditFieldLabel.Text = 'P received';
            app.PreceivedEditField = uieditfield(app.FriisTab, 'numeric');
            app.PreceivedEditField.Tooltip = {'Power received by the receiving antenna'};
            app.PtransmittedEditFieldLabel = uilabel(app.FriisTab);
            app.PtransmittedEditFieldLabel.Text = 'P transmitted';
            app.PtransmittedEditField = uieditfield(app.FriisTab, 'numeric');
            app.PtransmittedEditField.ValueChangedFcn = createCallbackFcn(app, @PtransmittedEditFieldValueChanged, true);
            app.PtransmittedEditField.Tooltip = {'Power transmitted from the transmit antenna'};
            app.frequencyEditField = uieditfield(app.FriisTab, 'numeric');
            app.frequencyEditField.Tooltip = {'re-radiation efficiency factor  in range 0 to 1'};
            app.frequencyEditField.Value = 1;
            app.frequencyMhzEditFieldLabel = uilabel(app.FriisTab);
            app.frequencyMhzEditFieldLabel.Tooltip = {'case ''4'''; '                    app.TabGroup.SelectedTab=app.DetectionTab'; ''};
            app.frequencyMhzEditFieldLabel.Text = 'frequency (Mhz)';
            app.D_txEditField = uieditfield(app.FriisTab, 'numeric');
            app.D_txEditField.Tooltip = {'re-radiation efficiency factor  in range 0 to 1'};
            app.D_txEditField.Value = 1;
            app.D_txLabel = uilabel(app.FriisTab);
            app.D_txLabel.Tooltip = {'case ''4'''; '                    app.TabGroup.SelectedTab=app.DetectionTab'; ''};
            app.D_txLabel.Text = 'D_tx';
            app.Image = uiimage(app.FriisTab);
            app.Image.ImageSource = 'D:\projecten\BAP\RFID-DSP\antenna.svg';
            app.Image_2 = uiimage(app.FriisTab);
            app.Image_2.ImageSource = 'D:\projecten\BAP\RFID-DSP\antenna.svg';
            app.Image_3 = uiimage(app.FriisTab);
            app.Image_3.ImageSource = 'D:\projecten\BAP\RFID-DSP\antenna.svg';
            app.Image_4 = uiimage(app.FriisTab);
            app.Image_4.ImageSource = 'D:\projecten\BAP\RFID-DSP\antenna.svg';
            app.D_tagEditField = uieditfield(app.FriisTab, 'numeric');
            app.D_tagEditField.Tooltip = {'re-radiation efficiency factor  in range 0 to 1'};
            app.D_tagEditField.Value = 1;
            app.D_tagLabel = uilabel(app.FriisTab);
            app.D_tagLabel.Tooltip = {'case ''4'''; '                    app.TabGroup.SelectedTab=app.DetectionTab'; ''};
            app.D_tagLabel.Text = 'D_tag';
            app.D_rxEditField = uieditfield(app.FriisTab, 'numeric');
            app.D_rxEditField.Tooltip = {'re-radiation efficiency factor  in range 0 to 1'};
            app.D_rxEditField.Value = 1;
            app.D_rxLabel = uilabel(app.FriisTab);
            app.D_rxLabel.Tooltip = {'case ''4'''; '                    app.TabGroup.SelectedTab=app.DetectionTab'; ''};
            app.D_rxLabel.Text = 'D_rx';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = systemModel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end